classdef DeltaMod_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        DeltamodulationDPCM1OrdnungLabel  matlab.ui.control.Label
        rekonstruiertesSignalytButton   matlab.ui.control.Button
        Abtastfolgex_anEditFieldLabel   matlab.ui.control.Label
        Abtastfolgex_anEditField        matlab.ui.control.EditField
        StufenhheQuantisiererEditFieldLabel  matlab.ui.control.Label
        StufenhheQuantisiererEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        x;
        delta;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Abtastfolgex_anEditField
        function Abtastfolgex_anEditFieldValueChanged(app, event)
            value = app.Abtastfolgex_anEditField.Value;
            app.x = str2double(strsplit(value,' '));
        end

        % Button pushed function: rekonstruiertesSignalytButton
        function rekonstruiertesSignalytButtonPushed(app, event)
            % close current plot
            close all; 
            % update user input
            app.Abtastfolgex_anEditFieldValueChanged();
            app.StufenhheQuantisiererEditFieldValueChanged();
            % locals for plot
            x_sz = numel(app.x);
            x_p = zeros(1,x_sz);
            d = zeros(1,x_sz);
            u = zeros(1,x_sz);
            y = zeros(1,x_sz);
            % Caluclation for n=1
            x_p(1) = 0;
            d(1) = app.x(1)-x_p(1);
            if d(1)>=0 
                u(1) = app.delta;
            else
                u(1) = -app.delta;
            end
            y(1) = app.x(1);
            % Caluclation for n>1
            for i=2:x_sz
                x_p(i) = y(i-1);
                d(i) = app.x(i)-x_p(i);
                switch d(i)
                    case d(i)*(d(i)>=0)
                        u(i) = app.delta;
                    case d(i)*(d(i)<0) 
                        u(i) = -app.delta; 
                end
                y(i) = u(i) + x_p(i);
            end
            % Plot
            stairs((1:x_sz),app.x); 
            hold on; 
            stairs((1:x_sz),y);
            grid on; 
            ylim([min([app.x,y])-1,max([app.x,y])+1]); xlim([0,x_sz+1]);
            legend("x(n) - Eingangssequenz ","y(n) - Rekonstruierte Ausgangssequenz", 'Location','southoutside');
            xlabel("n");
            title("Deltamodulation");
        end

        % Value changed function: StufenhheQuantisiererEditField
        function StufenhheQuantisiererEditFieldValueChanged(app, event)
            value = app.StufenhheQuantisiererEditField.Value;
            app.delta = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'how_to_DPCM_2.png';
            app.DeltamodulationDPCM1OrdnungLabel = uilabel(app.UIFigure);
            app.DeltamodulationDPCM1OrdnungLabel.Text = 'Deltamodulation (DPCM 1.Ordnung)';
            app.rekonstruiertesSignalytButton = uibutton(app.UIFigure, 'push');
            app.rekonstruiertesSignalytButton.ButtonPushedFcn = createCallbackFcn(app, @rekonstruiertesSignalytButtonPushed, true);
            app.rekonstruiertesSignalytButton.Text = 'rekonstruiertes Signal y(t)';
            app.Abtastfolgex_anEditFieldLabel = uilabel(app.UIFigure);
            app.Abtastfolgex_anEditFieldLabel.Text = 'Abtastfolge x_a(n)';
            app.Abtastfolgex_anEditField = uieditfield(app.UIFigure, 'text');
            app.Abtastfolgex_anEditField.ValueChangedFcn = createCallbackFcn(app, @Abtastfolgex_anEditFieldValueChanged, true);
            app.Abtastfolgex_anEditField.Value = '1 3 4 4 7 8 6 5 3 1';
            app.StufenhheQuantisiererEditFieldLabel = uilabel(app.UIFigure);
            app.StufenhheQuantisiererEditFieldLabel.Text = 'Stufenhöhe Quantisierer ∆';
            app.StufenhheQuantisiererEditField = uieditfield(app.UIFigure, 'numeric');
            app.StufenhheQuantisiererEditField.ValueChangedFcn = createCallbackFcn(app, @StufenhheQuantisiererEditFieldValueChanged, true);
            app.StufenhheQuantisiererEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaMod_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end